classdef Final_pds < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Mezclador       matlab.ui.Figure
        TS1             matlab.ui.container.Panel
        playS1          matlab.ui.control.Button
        pauseS1         matlab.ui.control.Button
        cargarS1        matlab.ui.control.Button
        volS1           matlab.ui.control.Slider
        Track1          matlab.ui.control.Label
        S1_fft          matlab.ui.control.UIAxes
        S1              matlab.ui.control.UIAxes
        TS2             matlab.ui.container.Panel
        cargarS2        matlab.ui.control.Button
        playS2          matlab.ui.control.Button
        pauseS2         matlab.ui.control.Button
        volS2           matlab.ui.control.Slider
        Track2          matlab.ui.control.Label
        S2_fft          matlab.ui.control.UIAxes
        S2              matlab.ui.control.UIAxes
        MezclaPanel     matlab.ui.container.Panel
        Mezclar         matlab.ui.control.Button
        playMix         matlab.ui.control.Button
        pauseMix        matlab.ui.control.Button
        filtro          matlab.ui.control.Button
        mezclaS1S2_fft  matlab.ui.control.UIAxes
        mezclaS1S2      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        s1;
        s2;
        Fs;
        ns1;
        ns2;
        N
        Y;
        Z;
        song1;
        song2;
        mix;
        mezcla;
        v1;
        v2;
    end
    
    properties (Access = private)
        %DialogApp % Description
    end
    
    methods (Access = public)
        
        function mezclar(app,s,fq)
            app.mezcla = audioplayer(s,fq);
                
            cla(app.mezclaS1S2,"reset");
            plot(app.mezclaS1S2,s);
                
            cla(app.mezclaS1S2_fft,"reset");
            nfft = fq;
            espectro = fft(s,nfft);
            ff = espectro(1:nfft/2);
            ff = ff/max(ff);
            f = (0:nfft/2-1)*fq/nfft;
            my = abs(ff).^2;
                
           plot(app.mezclaS1S2_fft,f,my)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: cargarS1
        function cargarS1ButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            if isequal(file,0)
                app.Track1.Text = 'Audio no seleccionado';
            else
                [app.s1,app.Fs] = audioread(file);
                 app.Track1.Text = file;
                 
                cla(app.S1,"reset");
                plot(app.S1,app.s1);
                
                app.ns1 = length(app.s1);
                nfft = app.Fs;
                espectro = fft(app.s1,nfft);
                ff = espectro(1:nfft/2);
                ff = ff/max(ff);
                
                f = (0:nfft/2-1)*app.Fs/nfft;
                my = abs(ff).^2;
                
                cla(app.S1_fft,"reset");
                
                plot(app.S1_fft,f,my);
            end


        end

        % Button pushed function: playS1
        function playS1ButtonPushed(app, event)
            if isempty(app.s1)
                disp('Audio no seleccionado');
            else
                app.v1 =app.volS1.Value/10;
                
                cla(app.S1,"reset");
                
                plot(app.S1,app.s1*app.v1);
                
                app.song1 = audioplayer(app.s1*app.v1,app.Fs);
                app.song1.play();
            end
        end

        % Button pushed function: cargarS2
        function cargarS2ButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            if isequal(file,0)
                app.Track2.Text = 'Audio no seleccionado';
            else
                [app.s2,app.Fs] = audioread(file);
                 app.Track2.Text = file;
                 
                cla(app.S2,"reset");
                plot(app.S2,app.s2);
                
                app.ns2 = length(app.s2);
                nfft = app.Fs;
                espectro = fft(app.s2,nfft);
                ff = espectro(1:nfft/2);
                ff = ff/max(ff);
                
                f = (0:nfft/2-1)*app.Fs/nfft;
                my = abs(ff).^2;
                
                cla(app.S2_fft,"reset");
                plot(app.S2_fft,f,my);
            end
        end

        % Button pushed function: playS2
        function playS2ButtonPushed(app, event)
            if isempty(app.s2)
                disp('Audio no seleccionado');
            else
                app.v2 =app.volS2.Value/10;
                
                cla(app.S2,"reset");
                plot(app.S2,app.s2*app.v2);
                
                app.song2 = audioplayer(app.s2*app.v2,app.Fs);
                app.song2.play();
            end
        end

        % Button pushed function: Mezclar
        function MezclarButtonPushed(app, event)
            if isempty(app.s1) || isempty(app.s2)
                disp('Falta un audio para mezclar');
            else
                app.N = min([app.ns1,app.ns2]);
                app.Y = app.s1(1:app.N);
                app.Z = app.s2(1:app.N);
                
                app.v1 =app.volS1.Value/10;
                app.v2 =app.volS2.Value/10;
                
                app.mix = (app.Y * app.v1) + (app.Z * app.v2);
           
                mezclar(app,app.mix,app.Fs);

                
            end
        end

        % Button pushed function: playMix
        function playMixButtonPushed(app, event)
            if isempty(app.s1) || isempty(app.s2)
                disp('Falta un audio para mezclar');
            else
                app.mezcla.play();
                
            end
        end

        % Button pushed function: pauseMix
        function pauseMixButtonPushed(app, event)
            if isempty(app.s1) || isempty(app.s2)
                disp('Falta un audio para mezclar');
            else
                app.mezcla.pause();
                %delete(app.DialogApp);
            end
        end

        % Button pushed function: pauseS1
        function pauseS1ButtonPushed(app, event)
            if isempty(app.s1)
                disp('Falta un audio para mezclar');
            else
                app.song1.pause();
                
            end
        end

        % Button pushed function: pauseS2
        function pauseS2ButtonPushed(app, event)
            if isempty(app.s2)
                disp('Falta un audio para mezclar');
            else
                app.song2.pause();
                
            end
        end

        % Button pushed function: filtro
        function filtroButtonPushed(app, event)
            selectFiltro(app,app.mix,app.Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Mezclador = uifigure('Visible', 'off');
            app.Mezclador.Name = 'MATLAB App';
            app.TS1 = uipanel(app.Mezclador);
            app.TS1.Title = 'Audio 1';
            app.playS1 = uibutton(app.TS1, 'push');
            app.playS1.ButtonPushedFcn = createCallbackFcn(app, @playS1ButtonPushed, true);
            app.playS1.Text = '►';
            app.pauseS1 = uibutton(app.TS1, 'push');
            app.pauseS1.ButtonPushedFcn = createCallbackFcn(app, @pauseS1ButtonPushed, true);
            app.pauseS1.Text = '■';
            app.cargarS1 = uibutton(app.TS1, 'push');
            app.cargarS1.ButtonPushedFcn = createCallbackFcn(app, @cargarS1ButtonPushed, true);
            app.cargarS1.Text = 'Cargar';
            app.volS1 = uislider(app.TS1);
            app.volS1.Limits = [0 10];
            app.volS1.Value = 5;
            app.Track1 = uilabel(app.TS1);
            app.Track1.Text = 'Audio 1';
            app.S1_fft = uiaxes(app.TS1);
            title(app.S1_fft, 'Title')
            xlabel(app.S1_fft, 'X')
            ylabel(app.S1_fft, 'Y')
            zlabel(app.S1_fft, 'Z')
            app.S1 = uiaxes(app.TS1);
            title(app.S1, 'Title')
            xlabel(app.S1, 'X')
            ylabel(app.S1, 'Y')
            zlabel(app.S1, 'Z')
            app.TS2 = uipanel(app.Mezclador);
            app.TS2.Title = 'Audio 2';
            app.cargarS2 = uibutton(app.TS2, 'push');
            app.cargarS2.ButtonPushedFcn = createCallbackFcn(app, @cargarS2ButtonPushed, true);
            app.cargarS2.Text = 'Cargar';
            app.playS2 = uibutton(app.TS2, 'push');
            app.playS2.ButtonPushedFcn = createCallbackFcn(app, @playS2ButtonPushed, true);
            app.playS2.Text = '►';
            app.pauseS2 = uibutton(app.TS2, 'push');
            app.pauseS2.ButtonPushedFcn = createCallbackFcn(app, @pauseS2ButtonPushed, true);
            app.pauseS2.Text = '■';
            app.volS2 = uislider(app.TS2);
            app.volS2.Limits = [0 10];
            app.volS2.Value = 5;
            app.Track2 = uilabel(app.TS2);
            app.Track2.Text = 'Audio 2';
            app.S2_fft = uiaxes(app.TS2);
            title(app.S2_fft, 'Title')
            xlabel(app.S2_fft, 'X')
            ylabel(app.S2_fft, 'Y')
            zlabel(app.S2_fft, 'Z')
            app.S2 = uiaxes(app.TS2);
            title(app.S2, 'Title')
            xlabel(app.S2, 'X')
            ylabel(app.S2, 'Y')
            zlabel(app.S2, 'Z')
            app.MezclaPanel = uipanel(app.Mezclador);
            app.MezclaPanel.Title = 'Mezcla';
            app.Mezclar = uibutton(app.MezclaPanel, 'push');
            app.Mezclar.ButtonPushedFcn = createCallbackFcn(app, @MezclarButtonPushed, true);
            app.Mezclar.Text = 'Mezclar';
            app.playMix = uibutton(app.MezclaPanel, 'push');
            app.playMix.ButtonPushedFcn = createCallbackFcn(app, @playMixButtonPushed, true);
            app.playMix.Text = '►';
            app.pauseMix = uibutton(app.MezclaPanel, 'push');
            app.pauseMix.ButtonPushedFcn = createCallbackFcn(app, @pauseMixButtonPushed, true);
            app.pauseMix.Text = '■';
            app.filtro = uibutton(app.MezclaPanel, 'push');
            app.filtro.ButtonPushedFcn = createCallbackFcn(app, @filtroButtonPushed, true);
            app.filtro.Text = 'Filtrar';
            app.mezclaS1S2_fft = uiaxes(app.MezclaPanel);
            title(app.mezclaS1S2_fft, 'Title')
            xlabel(app.mezclaS1S2_fft, 'X')
            ylabel(app.mezclaS1S2_fft, 'Y')
            zlabel(app.mezclaS1S2_fft, 'Z')
            app.mezclaS1S2 = uiaxes(app.MezclaPanel);
            title(app.mezclaS1S2, 'Title')
            xlabel(app.mezclaS1S2, 'X')
            ylabel(app.mezclaS1S2, 'Y')
            zlabel(app.mezclaS1S2, 'Z')
            % Show the figure after all components are created
            app.Mezclador.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_pds

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Mezclador)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Mezclador)
        end
    end
end