classdef selectFiltro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Filtro                      matlab.ui.Figure
        TipodefiltroButtonGroup     matlab.ui.container.ButtonGroup
        low                         matlab.ui.control.RadioButton
        high                        matlab.ui.control.RadioButton
        passband                    matlab.ui.control.RadioButton
        stop                        matlab.ui.control.RadioButton
        FiltrarButton               matlab.ui.control.Button
        OrdenLabel                  matlab.ui.control.Label
        FiltroButterworthLabel      matlab.ui.control.Label
        CancelarButton              matlab.ui.control.Button
        Knob                        matlab.ui.control.DiscreteKnob
        FrecuenciadecortealtaPanel  matlab.ui.container.Panel
        Fcalta                      matlab.ui.control.NumericEditField
        HzLabel                     matlab.ui.control.Label
        FrecuenciadecortebajaPanel  matlab.ui.container.Panel
        HzLabel_2                   matlab.ui.control.Label
        Fcbaja                      matlab.ui.control.NumericEditField
        bode                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Fs;
        mix;
        wn;
        n;
        num;
        den;
        h;
        w;
        filtro;
    end
    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, signal, freq)
            app.CallingApp = mainapp;
            app.mix = signal;
            app.Fs = freq;
            
            app.FrecuenciadecortebajaPanel.Visible = 'Off';
            app.FrecuenciadecortealtaPanel.Title = 'Frecuencia de corte';
        end

        % Button pushed function: FiltrarButton
        function FiltrarButtonPushed(app, event)
            sFiltrada = filter(app.num,app.den,app.mix);
            mezclar(app.CallingApp,sFiltrada,app.Fs)
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            delete(app)
        end

        % Callback function
        function FrecuenciadecortebajaPanelSizeChanged(app, event)
            position = app.FrecuenciadecortebajaPanel.Position;
                    
        end

        % Selection changed function: TipodefiltroButtonGroup
        function TipodefiltroButtonGroupSelectionChanged(app, event)
            selectedButton = app.TipodefiltroButtonGroup.SelectedObject.Text;
            disp(selectedButton);
            
             switch selectedButton
                 case 'Pasa bajas'
                     app.FrecuenciadecortebajaPanel.Visible = 'Off';
                     app.FrecuenciadecortealtaPanel.Title = 'Frecuencia de corte';
                     app.filtro = 'low';
                 case 'Pasa altas'
                     app.FrecuenciadecortebajaPanel.Visible = 'Off';
                     app.FrecuenciadecortealtaPanel.Title = 'Frecuencia de corte';
                     app.filtro = 'high';
                 case 'Pasa bandas'
                     app.FrecuenciadecortebajaPanel.Visible = 'On';
                     app.FrecuenciadecortealtaPanel.Title = 'Frecuencia de corte alta';
                     app.filtro = 'bandpass';
                 case 'Rechaza bandas'
                     app.FrecuenciadecortebajaPanel.Visible = 'On';
                     app.FrecuenciadecortealtaPanel.Title = 'Frecuencia de corte alta';
                     app.filtro = 'stop';
             end

        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            
             switch app.filtro
                 case 'bandpass'
                     app.wn = [app.Fcbaja.Value/(app.Fs/2) app.Fcalta.Value/(app.Fs/2)];
                 case 'stop'
                     app.wn = [app.Fcbaja.Value/(app.Fs/2) app.Fcalta.Value/(app.Fs/2)];
                 otherwise
                     app.wn = app.Fcalta.Value/(app.Fs/2);
             end

            
            app.n = str2num(value);
            [app.num,app.den] = butter(app.n,app.wn,app.filtro);
            [app.h,app.w] = freqz(app.num,app.den);
            cla(app.bode,"reset");
            plot(app.bode,app.w,abs(app.h));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Filtro = uifigure('Visible', 'off');
            app.Filtro.Name = 'MATLAB App';
            app.TipodefiltroButtonGroup = uibuttongroup(app.Filtro);
            app.TipodefiltroButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodefiltroButtonGroupSelectionChanged, true);
            app.TipodefiltroButtonGroup.Title = 'Tipo de filtro';
            app.low = uiradiobutton(app.TipodefiltroButtonGroup);
            app.low.Text = 'Pasa bajas';
            app.low.Value = true;
            app.high = uiradiobutton(app.TipodefiltroButtonGroup);
            app.high.Text = 'Pasa altas';
            app.passband = uiradiobutton(app.TipodefiltroButtonGroup);
            app.passband.Text = 'Pasa bandas';
            app.stop = uiradiobutton(app.TipodefiltroButtonGroup);
            app.stop.Text = 'Rechaza bandas';
            app.FiltrarButton = uibutton(app.Filtro, 'push');
            app.FiltrarButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrarButtonPushed, true);
            app.FiltrarButton.Text = 'Filtrar';
            app.OrdenLabel = uilabel(app.Filtro);
            app.OrdenLabel.Text = 'Orden';
            app.FiltroButterworthLabel = uilabel(app.Filtro);
            app.FiltroButterworthLabel.Text = 'Filtro Butterworth';
            app.CancelarButton = uibutton(app.Filtro, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Text = 'Cancelar';
            app.Knob = uiknob(app.Filtro, 'discrete');
            app.Knob.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = '1';
            app.FrecuenciadecortealtaPanel = uipanel(app.Filtro);
            app.FrecuenciadecortealtaPanel.Title = 'Frecuencia de corte alta';
            app.Fcalta = uieditfield(app.FrecuenciadecortealtaPanel, 'numeric');
            app.Fcalta.Limits = [0 Inf];
            app.Fcalta.RoundFractionalValues = 'on';
            app.HzLabel = uilabel(app.FrecuenciadecortealtaPanel);
            app.HzLabel.Text = 'Hz';
            app.FrecuenciadecortebajaPanel = uipanel(app.Filtro);
            app.FrecuenciadecortebajaPanel.Title = 'Frecuencia de corte baja';
            app.HzLabel_2 = uilabel(app.FrecuenciadecortebajaPanel);
            app.HzLabel_2.Text = 'Hz';
            app.Fcbaja = uieditfield(app.FrecuenciadecortebajaPanel, 'numeric');
            app.Fcbaja.Limits = [0 Inf];
            app.Fcbaja.RoundFractionalValues = 'on';
            app.bode = uiaxes(app.Filtro);
            title(app.bode, 'Title')
            xlabel(app.bode, 'Hz')
            zlabel(app.bode, 'Z')
            % Show the figure after all components are created
            app.Filtro.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selectFiltro(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Filtro)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Filtro)
        end
    end
end